classdef SAXS_TIFF_QuickView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        sldRange   matlab.ui.control.RangeSlider
        lblStatus  matlab.ui.control.Label
        btnLoad    matlab.ui.control.Button
        ax         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CurrentPath=pwd % Description
        T
        img
        dblBitDepth
    end

    methods (Access = private)

        function results = replot(app)
            if ~isempty(app.img)


                imshow(app.img,app.sldRange.Value,'Parent',app.ax);


            end
            figure(app.UIFigure);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnLoad
        function btnLoadButtonPushed(app, event)
            [file,path]=uigetfile({'*.tif;*.tiff'},'Select a TIFF image',app.CurrentPath,'MultiSelect','off');

            switch class(file)
                case  'char' % a file is specified
                    app.CurrentPath=path;
                    blnTIFFSuccess=true; % identifies TIFF reading status

                    % Handles TIFF loading error
                    try
                        app.T=Tiff(fullfile(path,file));
                    catch ME
                        % uialert(app.UIPanel,ME.message,'Error','Icon','error')
                        % app.lblStatus.Text=ME.message;
                        app.lblStatus.Value=ME.message;
                        blnTIFFSuccess=false;
                    end

                    if blnTIFFSuccess

                        blnImageSuccess=true;

                        try

                            app.img=app.T.read;
                            % app.allTags=app.T.getTagNames;

                            % Update UI controls

                            app.dblBitDepth=double(getTag(app.T,'BitsPerSample'));


                            % max_intensity=2.^dblBitDepth;

                            app.lblStatus.Text=file;


                            app.sldRange.Limits=double([0,max(app.img(:))]);
                            app.sldRange.Value=app.sldRange.Limits;


                        catch ME
                            app.lblStatus.Text=ME.message;
                            blnImageSuccess=false;
                        end


                        if blnTIFFSuccess && blnImageSuccess
                            app.replot


                        end


                    end


                case 'double'
                    app.lblStatus.Text="No file selected.";
                    blnTIFFSuccess=false;
            end
            figure(app.UIFigure);
        end

        % Value changed function: sldRange
        function sldRangeValueChanged(app, event)
            value = app.sldRange.Value;
            app.sldRange.Value=floor(value);
            if ~isempty(app.T)
                % app.sldRange.Limits(1)=max([0,floor(value/2)]);
                app.sldRange.Limits(2)=min([max(app.img(:)),floor(value(2).*2)]);
            end
            app.sldRange.Tooltip=join([num2str(app.sldRange.Value(1)),"~",num2str(app.sldRange.Value(2))]);

            app.replot

        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            % position = app.UIFigure.Position;
            app.replot
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SAXS TIFF QuickView';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.ax = uiaxes(app.UIFigure);
            app.ax.XTick = [];
            app.ax.XTickLabel = {};
            app.ax.YTick = [];
            app.ax.YTickLabel = {};
            app.ax.ZTick = [];
            app.ax.ZTickLabel = '';
            app.btnLoad = uibutton(app.UIFigure, 'push');
            app.btnLoad.ButtonPushedFcn = createCallbackFcn(app, @btnLoadButtonPushed, true);
            app.btnLoad.Text = 'Load...';
            app.lblStatus = uilabel(app.UIFigure);
            app.lblStatus.Text = '';
            app.sldRange = uislider(app.UIFigure, 'range');
            app.sldRange.ValueChangedFcn = createCallbackFcn(app, @sldRangeValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SAXS_TIFF_QuickView

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end