classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        lblStatus  matlab.ui.control.Label
        btnLoad    matlab.ui.control.Button
    end


    properties (Access = private)
        CurrentPath=pwd % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnLoad
        function btnLoadButtonPushed(app, event)
            [file,path]=uigetfile({'*.tif','*.tiff'},'Select a TIFF image',app.CurrentPath,'MultiSelect','off');

            switch class(file)
                case  'char' % a file is specified
                    app.CurrentPath=path;
                    blnND2Success=true; % identifies XML parsing failure
                    % blnDataSuccess=true; % identifies rheometric data mismatch

                    % Handles ND2 loading error
                    try
                        app.R=bfGetReader(fullfile(path,file));
                    catch ME
                        % uialert(app.UIPanel,ME.message,'Error','Icon','error')
                        % app.lblStatus.Text=ME.message;
                        app.txtMessage.Value=ME.message;
                        blnND2Success=false;
                    end
                case double
                    app.lblStatus.Text="No file selected.";
                    blnXMLSuccess=false;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.btnLoad = uibutton(app.UIFigure, 'push');
            app.btnLoad.ButtonPushedFcn = createCallbackFcn(app, @btnLoadButtonPushed, true);
            app.btnLoad.Text = 'Load...';
            app.lblStatus = uilabel(app.UIFigure);
            app.lblStatus.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end